classdef audioRecorderApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        StartrecordingButton  matlab.ui.control.Button
        StoprecordingButton   matlab.ui.control.Button
        RightPanel            matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        stopButtonPressed % self explanatory
        recordedAudio % full recorded audio
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartrecordingButton
        function StartrecordingButtonPushed(app, event)
            fs = 48000;
            driver = "DirectSound";
            devName = "default";
            recChans = 1;
            frameLen = 1024;
            app.stopButtonPressed = false;
            %reading object
            deviceReader = audioDeviceReader(fs,'Driver',driver,'Device',devName,...
                'ChannelMappingSource',"Property",'ChannelMapping',recChans,...
                'SamplesPerFrame',frameLen);
            %writing object
            fileWriter = dsp.AudioFileWriter('tmpAudio.wav','FileFormat','WAV','SampleRate',fs);
            %actually record from here
            disp('recording now')
            app.StartrecordingButton.BackgroundColor = 'g';
            app.StartrecordingButton.Enable = false;
            app.StoprecordingButton.Enable = true;
            nStart = 1;
            while ~app.stopButtonPressed
                nEnd = nStart+frameLen-1;
                acquiredAudio(nStart:nEnd,:) = deviceReader();
                fileWriter(acquiredAudio(nStart:nEnd,:));
                t(nStart:nEnd) = [nStart:nEnd]/fs;
                nStart = nEnd+1;
                %plot realtime
                plot(app.UIAxes,t,acquiredAudio);
                xlim(app.UIAxes,[0 t(end)])
                drawnow;
            end
            app.StartrecordingButton.BackgroundColor = [0.96,0.96,0.96];
            app.recordedAudio = acquiredAudio;
            
            release(deviceReader)
            release(fileWriter)
            disp('objects released')
           
            % write wavefile
            fname = ['recording_',datestr(now,'dd_mm_yyyy_HH_MM_SS'),'.wav'];
            audiowrite(fname,acquiredAudio,fs,'BitsPerSample',24);
            app.StartrecordingButton.Enable = true;
        end

        % Button pushed function: StoprecordingButton
        function StoprecordingButtonPushed(app, event)
            app.stopButtonPressed = true;
            app.StoprecordingButton.Enable = false;
            disp('recording stopped')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {402, 402};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {197, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartrecordingButton = uibutton(app.LeftPanel, 'push');
            app.StartrecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartrecordingButtonPushed, true);
            app.StartrecordingButton.Text = 'Start recording';
            app.StoprecordingButton = uibutton(app.LeftPanel, 'push');
            app.StoprecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StoprecordingButtonPushed, true);
            app.StoprecordingButton.Enable = 'off';
            app.StoprecordingButton.Text = 'Stop recording';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Recorded signal')
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'amplitude')
            app.UIAxes.YLim = [-1 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audioRecorderApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end